classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EqualizerUIFigure        matlab.ui.Figure
        Menu                     matlab.ui.container.Menu
        OpenFileMenu             matlab.ui.container.Menu
        SaveFileMenu             matlab.ui.container.Menu
        AboutMenu                matlab.ui.container.Menu
        SwitchDomain             matlab.ui.control.Switch
        SwitchSignal             matlab.ui.control.Switch
        PlayButton               matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        LFKnobLabel              matlab.ui.control.Label
        LFKnob                   matlab.ui.control.DiscreteKnob
        LMFKnobLabel             matlab.ui.control.Label
        LMFKnob                  matlab.ui.control.DiscreteKnob
        MFKnobLabel              matlab.ui.control.Label
        MFKnob                   matlab.ui.control.DiscreteKnob
        HMFKnobLabel             matlab.ui.control.Label
        HMFKnob                  matlab.ui.control.DiscreteKnob
        HFKnobLabel              matlab.ui.control.Label
        HFKnob                   matlab.ui.control.DiscreteKnob
        FilterTypeDropDownLabel  matlab.ui.control.Label
        FilterType               matlab.ui.control.DropDown
        SwitchLF                 matlab.ui.control.ToggleSwitch
        SwitchLMF                matlab.ui.control.ToggleSwitch
        SwitchMF                 matlab.ui.control.ToggleSwitch
        SwitchHMF                matlab.ui.control.ToggleSwitch
        SwitchHF                 matlab.ui.control.ToggleSwitch
        LampLF                   matlab.ui.control.Lamp
        LampLMF                  matlab.ui.control.Lamp
        LampMF                   matlab.ui.control.Lamp
        LampHMF                  matlab.ui.control.Lamp
        LampHF                   matlab.ui.control.Lamp
        EditFieldLF              matlab.ui.control.EditField
        dBLabel                  matlab.ui.control.Label
        EditFieldLMF             matlab.ui.control.EditField
        dBLabel_2                matlab.ui.control.Label
        EditFieldMF              matlab.ui.control.EditField
        dBLabel_3                matlab.ui.control.Label
        EditFieldHMF             matlab.ui.control.EditField
        dBLabel_4                matlab.ui.control.Label
        EditFieldHF              matlab.ui.control.EditField
        dBLabel_5                matlab.ui.control.Label
        UIResponse               matlab.ui.control.UIAxes
        UISignal                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Variables globales que usaremos en el codigo
        
        %Valores discretos y frecuencia de muestro del audio
        song
        Fs
        song_f
        L
        
        %Player handler
        player
        
        %Variables coeficientes de los filtros
        LFcoef
        LMFcoef
        MFcoef
        HMFcoef
        HFcoef
        
        %Variables auxiliares
        y_f1
        y_f2
        y_f3
        y_f4
        y_f5
        sum_filter
        res
        
        %Variables ficheros .mat
        FIRcoefs
        IIRcoefs
        
        %Fichero de audio cargado
        file_toopen
        %Fichero de audio a guardar
        file_tosave
         
    end
    
    methods (Access = private)
        %Funciones privadas del sistema   
        
        function plotSignals(app)
            %Función que modifica el contenido de las gráficas, cuando
            %cambiamos dominio, FIR o IIR y Ganancia
            
            filterEqu(app);


            switch string(app.SwitchDomain.Value)
                case "Time"
                     %Plot filtered and original
                    app.UISignal.Title.String = "Signal in Time";
                    app.UISignal.YLabel.String = "Amplitude";
                    app.UISignal.XLabel.String = "Time [s]";
                    
                    song_time = size(app.song, 1)/app.Fs;
                    x = 0:1/app.Fs:song_time;

                    plot(app.UISignal, x(2:end), app.song, 'k');
                    hold(app.UISignal, "on")
                    plot(app.UISignal, x(2:end), app.song_f);
                    axis(app.UISignal, "auto");
                    legend(app.UISignal, 'Original', 'Filtered');
                    app.UISignal.XScale = "linear";
                    hold(app.UISignal, "off")
                    
                case "Frequency"
                    %Plot filtered and original
                    app.UISignal.Title.String = 'Signal in Frequency';
                    app.UISignal.YLabel.String = 'Gain [dB]';
                    app.UISignal.XLabel.String = 'Frequency [Hz]';
                    
                    [Y, ~] = freqz(app.song, 1, app.L, app.Fs);
                    [X, ~] = freqz(app.song_f, 1, app.L, app.Fs);
                    
                    P1 = 10*log(abs(Y));
                    P2 = 10*log(abs(X));
                    
                    f = app.Fs*linspace(0,1,app.L);
                    
                    plot(app.UISignal, f, P1, 'k')
                    hold(app.UISignal, "on")
                    plot(app.UISignal, f, P2)
                    axis(app.UISignal, [20 app.Fs/2 0 inf]);
                    legend(app.UISignal, 'Original', 'Filtered')
                    app.UISignal.XScale = "log";
                    hold(app.UISignal, "off")
            end
            
            switch string(app.FilterType.Value)
                case "FIR"
                    app.sum_filter = app.LFcoef + app.LMFcoef + app.MFcoef + app.HMFcoef + app.HFcoef;
                    
                case "IIR"
                    N = 1000;
                    x = [1;zeros(N-1, 1)];
                    app.y_f1 = filter(app.LFcoef, app.IIRcoefs.f1_Den, x);
                    app.y_f2 = filter(app.LMFcoef, app.IIRcoefs.f2_Den, x);
                    app.y_f3 = filter(app.MFcoef, app.IIRcoefs.f3_Den, x);
                    app.y_f4 = filter(app.HMFcoef, app.IIRcoefs.f4_Den, x);
                    app.y_f5 = filter(app.HFcoef, app.IIRcoefs.f5_Den, x);
                    app.sum_filter = app.y_f1' + app.y_f2' + app.y_f3' + app.y_f4' + app.y_f5';
            end

            [Y, f] = freqz(app.sum_filter, 1, length(app.sum_filter), app.Fs);

            aux = 10*log(abs(Y));
            %Opcional colores por filtros
            plot(app.UIResponse, f(1:11), aux(1:11), 'b-')
            hold(app.UIResponse, "on")
            axis(app.UIResponse, [20 app.Fs/2 -40 40]);
            app.UIResponse.XScale = "log";
            plot(app.UIResponse, f(11:34), aux(11:34), 'm-')
            plot(app.UIResponse, f(34:102), aux(34:102), 'g-')
            plot(app.UIResponse, f(102:306), aux(102:306), 'r-')
            plot(app.UIResponse, f(306:end), aux(306:end), 'k-')
            hold(app.UIResponse, "off")
            
        end

        
        function getCoeficients(app)
            %Función que actualiza los coeficientes de los filtros cuando
            %se modifique la ganancia o el tipo de filtro(FIR o IIR)

            switch string(app.FilterType.Value)
                case "FIR"
                    %Opcional activar/desactivar filtros
                    if strcmp(app.SwitchLF.Value, "On")
                        app.LFcoef = app.FIRcoefs.Filter1.*db2mag(str2double(app.LFKnob.Value));
                    else
                        app.LFcoef = app.FIRcoefs.Filter1.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchLMF.Value, "On")
                        app.LMFcoef = app.FIRcoefs.Filter2.*db2mag(str2double(app.LMFKnob.Value));
                    else
                        app.LMFcoef = app.FIRcoefs.Filter2.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchMF.Value, "On")
                        app.MFcoef = app.FIRcoefs.Filter3.*db2mag(str2double(app.MFKnob.Value));
                    else
                        app.MFcoef = app.FIRcoefs.Filter3.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchHMF.Value, "On")
                        app.HMFcoef = app.FIRcoefs.Filter4.*db2mag(str2double(app.HMFKnob.Value));
                    else
                        app.HMFcoef = app.FIRcoefs.Filter4.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchLMF.Value, "On")
                        app.HFcoef = app.FIRcoefs.Filter5.*db2mag(str2double(app.HFKnob.Value));
                    else
                        app.HFcoef = app.FIRcoefs.Filter5.*db2mag(0);
                    end
                    
                case "IIR"
                    
                    if strcmp(app.SwitchLF.Value, "On")
                        app.LFcoef = app.IIRcoefs.f1_Num.*db2mag(str2double(app.LFKnob.Value));
                    else
                        app.LFcoef = app.IIRcoefs.f1_Num.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchLMF.Value, "On")
                        app.LMFcoef = app.IIRcoefs.f2_Num.*db2mag(str2double(app.LMFKnob.Value));
                    else
                        app.LMFcoef = app.IIRcoefs.f2_Num.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchMF.Value, "On")
                        app.MFcoef = app.IIRcoefs.f3_Num.*db2mag(str2double(app.MFKnob.Value));
                    else
                        app.MFcoef = app.IIRcoefs.f3_Num.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchHMF.Value, "On")
                        app.HMFcoef = app.IIRcoefs.f4_Num.*db2mag(str2double(app.HMFKnob.Value));
                    else
                        app.HMFcoef = app.IIRcoefs.f4_Num.*db2mag(0);
                    end
                    
                    if strcmp(app.SwitchLMF.Value, "On")
                        app.HFcoef = app.IIRcoefs.f5_Num.*db2mag(str2double(app.HFKnob.Value));
                    else
                        app.HFcoef = app.IIRcoefs.f5_Num.*db2mag(0);
                    end
                    
            end
        end


        function filterEqu(app)
            %Aplicará el proceso de filtrado de la señal cuando se
            %modifique la ganancia o cambien los filtros(FIR o IIR)
            getCoeficients(app);
            
            switch string(app.FilterType.Value)
                case "FIR"
                    app.y_f1 = filter(app.LFcoef, 1, app.song);
                    app.y_f2 = filter(app.LMFcoef, 1, app.song);
                    app.y_f3 = filter(app.MFcoef, 1, app.song);
                    app.y_f4 = filter(app.HMFcoef, 1, app.song);
                    app.y_f5 = filter(app.HFcoef, 1, app.song);
                case "IIR"
                    app.y_f1 = filter(app.LFcoef, app.IIRcoefs.f1_Den, app.song);
                    app.y_f2 = filter(app.LMFcoef, app.IIRcoefs.f2_Den, app.song);
                    app.y_f3 = filter(app.MFcoef, app.IIRcoefs.f3_Den, app.song);
                    app.y_f4 = filter(app.HMFcoef, app.IIRcoefs.f4_Den, app.song);
                    app.y_f5 = filter(app.HFcoef, app.IIRcoefs.f5_Den, app.song);
            end
            app.song_f = app.y_f1 + app.y_f2 + app.y_f3 + app.y_f4 + app.y_f5; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Cargar archivos .mat i meter la info a los componentes correspondientes e iniciar valores
            app.FIRcoefs = load('filtersFIR.mat');
            app.IIRcoefs = load('filtersIIR.mat');

            %Stop button error if no file loaded
            app.Fs = 44100;
            app.song_f = 1;
            app.song = 1;
            app.L = 1;

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            %Mostrar un panel con los integrantes del grupo
            message = sprintf('Alumnes: \n\n\t Alejandro Ferrer Trinxet \n\t Gerard Pérez Iglesias \n\t Marc Legarre Lluch');
            uialert(app.EqualizerUIFigure, message, 'About', 'Icon', 'info')
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuSelected(app, event)
            app.file_toopen = uigetfile('*.wav');
            if app.file_toopen ~= 0
                [app.song, app.Fs] = audioread(app.file_toopen);
                app.song = app.song(:,1);
                app.L = length(app.song);
                app.player = audioplayer(app.song, app.Fs);
                plotSignals(app);
            end
        end

        % Menu selected function: SaveFileMenu
        function SaveFileMenuSelected(app, event)
            app.file_tosave = uiputfile('filtered.wav');
            if app.file_tosave ~= 0
                audiowrite(app.file_tosave, app.song_f, app.Fs);
            end
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            switch string(app.SwitchSignal.Value)
                case "Original"
                    app.player = audioplayer(app.song, app.Fs);
                case "Filtered"
                    filterEqu(app);
                    app.player = audioplayer(app.song_f, app.Fs);
            end
            play(app.player);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if isplaying(app.player)
                stop(app.player);
            end
        end

        % Value changed function: SwitchDomain
        function SwitchDomainValueChanged(app, event)
            plotSignals(app);
        end

        % Value changed function: SwitchSignal
        function SwitchSignalValueChanged(app, event)
            plotSignals(app);
        end

        % Value changed function: FilterType
        function FilterTypeValueChanged(app, event)
            plotSignals(app);
        end

        % Value changed function: LFKnob
        function LFKnobValueChanged(app, event)
            if strcmp(app.SwitchLF.Value, "On")
                plotSignals(app);
            end
            app.EditFieldLF.Value = app.LFKnob.Value;
        end

        % Value changed function: LMFKnob
        function LMFKnobValueChanged(app, event)
            if strcmp(app.SwitchLMF.Value, "On")
                plotSignals(app);
            end
            app.EditFieldLMF.Value = app.LMFKnob.Value;
        end

        % Value changed function: MFKnob
        function MFKnobValueChanged(app, event)
            if strcmp(app.SwitchMF.Value, "On")
                plotSignals(app);
            end
            app.EditFieldMF.Value = app.MFKnob.Value;
        end

        % Value changed function: HMFKnob
        function HMFKnobValueChanged(app, event)
            if strcmp(app.SwitchHMF.Value, "On")
                plotSignals(app);
            end
            app.EditFieldHMF.Value = app.HMFKnob.Value;
        end

        % Value changed function: HFKnob
        function HFKnobValueChanged(app, event)
            if strcmp(app.SwitchHF.Value, "On")
                plotSignals(app);
            end
            app.EditFieldHF.Value = app.HFKnob.Value;
        end

        % Value changed function: SwitchLF
        function SwitchLFValueChanged(app, event)
            %Opcional, Led indicando activo/desactivado
            value = app.SwitchLF.Value;
            switch string(value)
                case "On"
                    app.LampLF.Enable = "on";
                case "Off"
                    app.LampLF.Enable = "off";
            end
            plotSignals(app);
        end

        % Value changed function: SwitchLMF
        function SwitchLMFValueChanged(app, event)
            %Opcional, Led indicando activo/desactivado
            value = app.SwitchLMF.Value;
            switch string(value)
                case "On"
                    app.LampLMF.Enable = "on";
                case "Off"
                    app.LampLMF.Enable = "off";
            end
            plotSignals(app);
        end

        % Value changed function: SwitchMF
        function SwitchMFValueChanged(app, event)
            %Opcional, Led indicando activo/desactivado
            value = app.SwitchMF.Value;
            switch string(value)
                case "On"
                    app.LampMF.Enable = "on";
                case "Off"
                    app.LampMF.Enable = "off";
            end
            plotSignals(app);
        end

        % Value changed function: SwitchHMF
        function SwitchHMFValueChanged(app, event)
            %Opcional, Led indicando activo/desactivado
            value = app.SwitchHMF.Value;
            switch string(value)
                case "On"
                    app.LampHMF.Enable = "on";
                case "Off"
                    app.LampHMF.Enable = "off";
            end
            plotSignals(app);
        end

        % Value changed function: SwitchHF
        function SwitchHFValueChanged(app, event)
            %Opcional, Led indicando activo/desactivado
            value = app.SwitchHF.Value;
            switch string(value)
                case "On"
                    app.LampHF.Enable = "on";
                case "Off"
                    app.LampHF.Enable = "off";
            end
            plotSignals(app);
        end

        % Value changed function: EditFieldLF
        function EditFieldLFValueChanged(app, event)
            value = app.EditFieldLF.Value;
            a = round(str2double(value));
            if a < -30
                app.res = -30;
            elseif a > 30
                app.res = 30;
            else
                app.res = round(a/5)*5;
            end
            app.EditFieldLF.Value = num2str(app.res);
            app.LFKnob.Value = num2str(app.res);
            plotSignals(app);
        end

        % Value changed function: EditFieldLMF
        function EditFieldLMFValueChanged(app, event)
            value = app.EditFieldLMF.Value;
            a = round(str2double(value));
            if a < -30
                app.res = -30;
            elseif a > 30
                app.res = 30;
            else
                app.res = round(a/5)*5;
            end
            app.EditFieldLMF.Value = num2str(app.res);
            app.LMFKnob.Value = num2str(app.res);
            plotSignals(app);
        end

        % Value changed function: EditFieldMF
        function EditFieldMFValueChanged(app, event)
            value = app.EditFieldMF.Value;
            a = round(str2double(value));
            if a < -30
                app.res = -30;
            elseif a > 30
                app.res = 30;
            else
                app.res = round(a/5)*5;
            end
            app.EditFieldMF.Value = num2str(app.res);
            app.MFKnob.Value = num2str(app.res);
            plotSignals(app);
        end

        % Value changed function: EditFieldHMF
        function EditFieldHMFValueChanged(app, event)
            value = app.EditFieldHMF.Value;
            a = round(str2double(value));
            if a < -30
                app.res = -30;
            elseif a > 30
                app.res = 30;
            else
                app.res = round(a/5)*5;
            end
            app.EditFieldHMF.Value = num2str(app.res);
            app.HMFKnob.Value = num2str(app.res);
            plotSignals(app);
        end

        % Value changed function: EditFieldHF
        function EditFieldHFValueChanged(app, event)
            value = app.EditFieldHF.Value;
            a = round(str2double(value));
            if a < -30
                app.res = -30;
            elseif a > 30
                app.res = 30;
            else
                app.res = round(a/5)*5;
            end
            app.EditFieldHF.Value = num2str(app.res);
            app.HFKnob.Value = num2str(app.res);
            plotSignals(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EqualizerUIFigure = uifigure('Visible', 'off');
            app.EqualizerUIFigure.Name = 'Equalizer';
            app.Menu = uimenu(app.EqualizerUIFigure);
            app.Menu.Text = 'Menu';
            app.OpenFileMenu = uimenu(app.Menu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFileMenu.Text = 'Open File';
            app.SaveFileMenu = uimenu(app.Menu);
            app.SaveFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFileMenuSelected, true);
            app.SaveFileMenu.Text = 'Save File';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.SwitchDomain = uiswitch(app.EqualizerUIFigure, 'slider');
            app.SwitchDomain.Items = {'Frequency', 'Time'};
            app.SwitchDomain.ValueChangedFcn = createCallbackFcn(app, @SwitchDomainValueChanged, true);
            app.SwitchDomain.Value = 'Frequency';
            app.SwitchSignal = uiswitch(app.EqualizerUIFigure, 'slider');
            app.SwitchSignal.Items = {'Original', 'Filtered'};
            app.SwitchSignal.ValueChangedFcn = createCallbackFcn(app, @SwitchSignalValueChanged, true);
            app.SwitchSignal.Value = 'Original';
            app.PlayButton = uibutton(app.EqualizerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.EqualizerUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Image = uiimage(app.EqualizerUIFigure);
            app.Image.ImageSource = 'logo.jpg';
            app.LFKnobLabel = uilabel(app.EqualizerUIFigure);
            app.LFKnobLabel.Text = 'LF';
            app.LFKnob = uiknob(app.EqualizerUIFigure, 'discrete');
            app.LFKnob.Items = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.LFKnob.ValueChangedFcn = createCallbackFcn(app, @LFKnobValueChanged, true);
            app.LFKnob.Value = '0';
            app.LMFKnobLabel = uilabel(app.EqualizerUIFigure);
            app.LMFKnobLabel.Text = 'LMF';
            app.LMFKnob = uiknob(app.EqualizerUIFigure, 'discrete');
            app.LMFKnob.Items = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.LMFKnob.ValueChangedFcn = createCallbackFcn(app, @LMFKnobValueChanged, true);
            app.LMFKnob.Value = '0';
            app.MFKnobLabel = uilabel(app.EqualizerUIFigure);
            app.MFKnobLabel.Text = 'MF';
            app.MFKnob = uiknob(app.EqualizerUIFigure, 'discrete');
            app.MFKnob.Items = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.MFKnob.ValueChangedFcn = createCallbackFcn(app, @MFKnobValueChanged, true);
            app.MFKnob.Value = '0';
            app.HMFKnobLabel = uilabel(app.EqualizerUIFigure);
            app.HMFKnobLabel.Text = 'HMF';
            app.HMFKnob = uiknob(app.EqualizerUIFigure, 'discrete');
            app.HMFKnob.Items = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.HMFKnob.ValueChangedFcn = createCallbackFcn(app, @HMFKnobValueChanged, true);
            app.HMFKnob.Value = '0';
            app.HFKnobLabel = uilabel(app.EqualizerUIFigure);
            app.HFKnobLabel.Text = 'HF';
            app.HFKnob = uiknob(app.EqualizerUIFigure, 'discrete');
            app.HFKnob.Items = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.HFKnob.ValueChangedFcn = createCallbackFcn(app, @HFKnobValueChanged, true);
            app.HFKnob.Value = '0';
            app.FilterTypeDropDownLabel = uilabel(app.EqualizerUIFigure);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterType = uidropdown(app.EqualizerUIFigure);
            app.FilterType.Items = {'FIR', 'IIR'};
            app.FilterType.ValueChangedFcn = createCallbackFcn(app, @FilterTypeValueChanged, true);
            app.FilterType.Value = 'FIR';
            app.SwitchLF = uiswitch(app.EqualizerUIFigure, 'toggle');
            app.SwitchLF.ValueChangedFcn = createCallbackFcn(app, @SwitchLFValueChanged, true);
            app.SwitchLF.Value = 'On';
            app.SwitchLMF = uiswitch(app.EqualizerUIFigure, 'toggle');
            app.SwitchLMF.ValueChangedFcn = createCallbackFcn(app, @SwitchLMFValueChanged, true);
            app.SwitchLMF.Value = 'On';
            app.SwitchMF = uiswitch(app.EqualizerUIFigure, 'toggle');
            app.SwitchMF.ValueChangedFcn = createCallbackFcn(app, @SwitchMFValueChanged, true);
            app.SwitchMF.Value = 'On';
            app.SwitchHMF = uiswitch(app.EqualizerUIFigure, 'toggle');
            app.SwitchHMF.ValueChangedFcn = createCallbackFcn(app, @SwitchHMFValueChanged, true);
            app.SwitchHMF.Value = 'On';
            app.SwitchHF = uiswitch(app.EqualizerUIFigure, 'toggle');
            app.SwitchHF.ValueChangedFcn = createCallbackFcn(app, @SwitchHFValueChanged, true);
            app.SwitchHF.Value = 'On';
            app.LampLF = uilamp(app.EqualizerUIFigure);
            app.LampLMF = uilamp(app.EqualizerUIFigure);
            app.LampMF = uilamp(app.EqualizerUIFigure);
            app.LampHMF = uilamp(app.EqualizerUIFigure);
            app.LampHF = uilamp(app.EqualizerUIFigure);
            app.EditFieldLF = uieditfield(app.EqualizerUIFigure, 'text');
            app.EditFieldLF.ValueChangedFcn = createCallbackFcn(app, @EditFieldLFValueChanged, true);
            app.EditFieldLF.Value = '0';
            app.dBLabel = uilabel(app.EqualizerUIFigure);
            app.dBLabel.Text = 'dB';
            app.EditFieldLMF = uieditfield(app.EqualizerUIFigure, 'text');
            app.EditFieldLMF.ValueChangedFcn = createCallbackFcn(app, @EditFieldLMFValueChanged, true);
            app.EditFieldLMF.Value = '0';
            app.dBLabel_2 = uilabel(app.EqualizerUIFigure);
            app.dBLabel_2.Text = 'dB';
            app.EditFieldMF = uieditfield(app.EqualizerUIFigure, 'text');
            app.EditFieldMF.ValueChangedFcn = createCallbackFcn(app, @EditFieldMFValueChanged, true);
            app.EditFieldMF.Value = '0';
            app.dBLabel_3 = uilabel(app.EqualizerUIFigure);
            app.dBLabel_3.Text = 'dB';
            app.EditFieldHMF = uieditfield(app.EqualizerUIFigure, 'text');
            app.EditFieldHMF.ValueChangedFcn = createCallbackFcn(app, @EditFieldHMFValueChanged, true);
            app.EditFieldHMF.Value = '0';
            app.dBLabel_4 = uilabel(app.EqualizerUIFigure);
            app.dBLabel_4.Text = 'dB';
            app.EditFieldHF = uieditfield(app.EqualizerUIFigure, 'text');
            app.EditFieldHF.ValueChangedFcn = createCallbackFcn(app, @EditFieldHFValueChanged, true);
            app.EditFieldHF.Value = '0';
            app.dBLabel_5 = uilabel(app.EqualizerUIFigure);
            app.dBLabel_5.Text = 'dB';
            app.UIResponse = uiaxes(app.EqualizerUIFigure);
            title(app.UIResponse, 'Filter Response')
            xlabel(app.UIResponse, 'Freq [Hz]')
            ylabel(app.UIResponse, 'Gain [dB]')
            zlabel(app.UIResponse, 'Z')
            app.UISignal = uiaxes(app.EqualizerUIFigure);
            title(app.UISignal, 'Signal')
            xlabel(app.UISignal, 'X')
            ylabel(app.UISignal, 'Gain [dB]')
            zlabel(app.UISignal, 'Z')
            % Show the figure after all components are created
            app.EqualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EqualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EqualizerUIFigure)
        end
    end
end